classdef VetProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GaitCyclesPanel                matlab.ui.container.Panel
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes3_2                      matlab.ui.control.UIAxes
        UIAxes3_3                      matlab.ui.control.UIAxes
        UIAxes3_4                      matlab.ui.control.UIAxes
        RGT_RLELabel                   matlab.ui.control.Label
        RLO_RLSLabel                   matlab.ui.control.Label
        RDS_CentroidLabel              matlab.ui.control.Label
        T1_CentroidLabel               matlab.ui.control.Label
        RGT_RLE_1                      matlab.ui.control.Lamp
        RLO_RLS_1                      matlab.ui.control.Lamp
        RDS_CENTROID_1                 matlab.ui.control.Lamp
        T1_CENTROID_1                  matlab.ui.control.Lamp
        RGT_RLELabel_2                 matlab.ui.control.Label
        RLO_RLSLabel_2                 matlab.ui.control.Label
        RDS_CentroidLabel_2            matlab.ui.control.Label
        T1_CentroidLabel_2             matlab.ui.control.Label
        RGT_RLE_2                      matlab.ui.control.Lamp
        RLO_RLS_2                      matlab.ui.control.Lamp
        RDS_CENTROID_2                 matlab.ui.control.Lamp
        T1_CENTROID_2                  matlab.ui.control.Lamp
        RGT_RLELabel_3                 matlab.ui.control.Label
        RLO_RLSLabel_3                 matlab.ui.control.Label
        RDS_CentroidLabel_3            matlab.ui.control.Label
        T1_CentroidLabel_3             matlab.ui.control.Label
        RGT_RLE_3                      matlab.ui.control.Lamp
        RLO_RLS_3                      matlab.ui.control.Lamp
        RDS_CENTROID_3                 matlab.ui.control.Lamp
        T1_CENTROID_3                  matlab.ui.control.Lamp
        UIAxes3_5                      matlab.ui.control.UIAxes
        RGT_RLELabel_4                 matlab.ui.control.Label
        RLO_RLSLabel_4                 matlab.ui.control.Label
        RDS_CentroidLabel_4            matlab.ui.control.Label
        T1_CentroidLabel_4             matlab.ui.control.Label
        RGT_RLE_4                      matlab.ui.control.Lamp
        RLO_RLS_4                      matlab.ui.control.Lamp
        RDS_CENTROID_4                 matlab.ui.control.Lamp
        T1_CENTROID_4                  matlab.ui.control.Lamp
        RGT_RLE_o1                     matlab.ui.control.CheckBox
        ACB_R5MLabel                   matlab.ui.control.Label
        ACB_R5M_1                      matlab.ui.control.Lamp
        ACB_R5MLabel_2                 matlab.ui.control.Label
        ACB_R5M_2                      matlab.ui.control.Lamp
        ACB_R5MLabel_3                 matlab.ui.control.Label
        ACB_R5M_3                      matlab.ui.control.Lamp
        ACB_R5MLabel_4                 matlab.ui.control.Label
        ACB_R5M_4                      matlab.ui.control.Lamp
        RLO_RLS_o1                     matlab.ui.control.CheckBox
        RDS_Centroid_o1                matlab.ui.control.CheckBox
        T1_Centroid_o1                 matlab.ui.control.CheckBox
        ACB_R5M_o1                     matlab.ui.control.CheckBox
        RLO_RLS_o2                     matlab.ui.control.CheckBox
        RDS_Centroid_o2                matlab.ui.control.CheckBox
        T1_Centroid_o2                 matlab.ui.control.CheckBox
        ACB_R5M_o2                     matlab.ui.control.CheckBox
        RLO_RLS_o3                     matlab.ui.control.CheckBox
        RDS_Centroid_o3                matlab.ui.control.CheckBox
        T1_Centroid_o3                 matlab.ui.control.CheckBox
        ACB_R5M_o3                     matlab.ui.control.CheckBox
        RLO_RLS_o4                     matlab.ui.control.CheckBox
        RDS_Centroid_o4                matlab.ui.control.CheckBox
        T1_Centroid_o4                 matlab.ui.control.CheckBox
        ACB_R5M_o4                     matlab.ui.control.CheckBox
        RGT_RLE_o2                     matlab.ui.control.CheckBox
        RGT_RLE_o3                     matlab.ui.control.CheckBox
        RGT_RLE_o4                     matlab.ui.control.CheckBox
        GC1_OVR                        matlab.ui.control.CheckBox
        GC2_OVR                        matlab.ui.control.CheckBox
        GC3_OVR                        matlab.ui.control.CheckBox
        GC4_OVR                        matlab.ui.control.CheckBox
        GoodgaitcyclesEditFieldLabel   matlab.ui.control.Label
        GoodgaitcyclesEditField        matlab.ui.control.NumericEditField
        GaitCycleLampLabel             matlab.ui.control.Label
        GaitCycleLamp                  matlab.ui.control.Lamp
        GaitCycleLamp_2Label           matlab.ui.control.Label
        GaitCycleLamp_2                matlab.ui.control.Lamp
        GaitCycleLamp_3Label           matlab.ui.control.Label
        GaitCycleLamp_3                matlab.ui.control.Lamp
        GaitCycleLamp_4Label           matlab.ui.control.Label
        GaitCycleLamp_4                matlab.ui.control.Lamp
        MarkerReconstructionsLabel     matlab.ui.control.Label
        T1CheckBox                     matlab.ui.control.CheckBox
        DSCheckBox                     matlab.ui.control.CheckBox
        ACBCheckBox                    matlab.ui.control.CheckBox
        MECheckBox                     matlab.ui.control.CheckBox
        MSCheckBox                     matlab.ui.control.CheckBox
        MCP2CheckBox                   matlab.ui.control.CheckBox
        StartButton                    matlab.ui.control.Button
        LandmarkDropDown               matlab.ui.control.DropDown
        PermittableErrorLengthmmEditFieldLabel  matlab.ui.control.Label
        PermittableErrorLengthmmEditField  matlab.ui.control.EditField
        ShowstaticvsdyngraphsCheckBox  matlab.ui.control.CheckBox
        ChooseDynamicTrialButton       matlab.ui.control.Button
        ChooseStaticTrialButton        matlab.ui.control.Button
        LoadedDynamicTrialEditFieldLabel  matlab.ui.control.Label
        LoadedDynamicTrialEditField    matlab.ui.control.EditField
        LoadedStaticTrialEditFieldLabel  matlab.ui.control.Label
        LoadedStaticTrialEditField     matlab.ui.control.EditField
        AutomaticdirectionadjustmentCheckBox  matlab.ui.control.CheckBox
        ShowreconstructedmarkergraphsCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        dynamic_trial;
        static_trial;
        name;
        error_length;
        landmark_coord;
        cycle_time;
        trimmed_coord;
        gait_cycle_locations;
        graphs;
        segment_error_checks;
        good_cycle_count = 0;
        gait_cycle_count = 0;
        override_arr = [];
        frame_errors = 0;
        show_graphs = 0;
        anatomical_region = "";
        gc_override_arr;
        opp_direction;
        dir_adjust;
        show_reconstr_graphs;
    end
    
    methods (Access = private)       
        
        function results = display_file_not_found(app)
            f = errordlg('Unable to open the specified spreadsheet(s). The file may not exist or is not within the current working directory.','File Error');
            results = "";
        end
        
        function results = plot_cycles(app)     
        
        app.graphs = [app.UIAxes3_2 app.UIAxes3_3 app.UIAxes3_4 app.UIAxes3_5];
        
            plot(app.UIAxes3,app.cycle_time,app.landmark_coord);
            
            app.gait_cycle_count = length(app.gait_cycle_locations);
            gait_cycle_count = length(app.gait_cycle_locations);
            
            for n = 1:gait_cycle_count
                s_frame(n) = app.gait_cycle_locations(n).start_frame;
                e_frame(n) = app.gait_cycle_locations(n).end_frame;
                segment_checks(n) = app.gait_cycle_locations(n).seg_checks;
                
                if(app.anatomical_region == "Hindlimb")
                    if(app.gc_override_arr(n))
                      is_good(n) = ~segment_checks(n).GT_LEP;
                    else
                      is_good(n) = segment_checks(n).GT_LEP;
                    end
                else               
                    if(app.gc_override_arr(n))
                      is_good(n) = ~segment_checks(n).RGT_RLE;
                    else
                      is_good(n) = segment_checks(n).RGT_RLE;
                    end
                end
                             
                
                if(is_good(n)== 1)
                    app.good_cycle_count = app.good_cycle_count + 1;
                end
            end
            
            app.segment_error_checks = segment_checks;
            
            app.GoodgaitcyclesEditField.Value = app.good_cycle_count;
            
            if(is_good(1))
                plot(app.UIAxes3_2,s_frame(1):e_frame(1),app.trimmed_coord(s_frame(1):e_frame(1)))
                app.GaitCycleLamp.Color='green';
            else
                plot(app.UIAxes3_2,s_frame(1):e_frame(1),app.trimmed_coord(s_frame(1):e_frame(1)))
                app.GaitCycleLamp.Color='red';
            end       
           
            if(gait_cycle_count >= 2 && is_good(2))
                app.UIAxes3_3.Visible='on';
                plot(app.UIAxes3_3,s_frame(2):e_frame(2),app.trimmed_coord(s_frame(2):e_frame(2)));
                app.GaitCycleLamp_2.Color='green';
            else
                if(gait_cycle_count < 2)
                    plot(app.UIAxes3_3,0,0);
                    app.UIAxes3_3.Visible='off';
                    app.GaitCycleLamp_2.Color='white';
                else
                    app.UIAxes3_3.Visible='on';
                    plot(app.UIAxes3_3,s_frame(2):e_frame(2),app.trimmed_coord(s_frame(2):e_frame(2)));
                    app.GaitCycleLamp_2.Color='red';
                end
            end
            
            if(gait_cycle_count >= 3 && is_good(3))
                app.UIAxes3_4.Visible='on';
                plot(app.UIAxes3_4,s_frame(3):e_frame(3),app.trimmed_coord(s_frame(3):e_frame(3)));
                app.GaitCycleLamp_3.Enable='on';
                app.GaitCycleLamp_3.Color='green';
            else
                if(gait_cycle_count < 3)
                    app.UIAxes3_4.Visible='off';
                    plot(app.UIAxes3_4,0,0);
                    app.GaitCycleLamp_3.Color='white';
                else
                    app.UIAxes3_4.Visible='on';
                    plot(app.UIAxes3_4,s_frame(3):e_frame(3),app.trimmed_coord(s_frame(3):e_frame(3)));
                    app.GaitCycleLamp_3.Color='red';
                end
            end
            
            if(gait_cycle_count >= 4 && is_good(4))
                app.UIAxes3_5.Visible='on';
                plot(app.UIAxes3_5,s_frame(4):e_frame(4),app.trimmed_coord(s_frame(4):e_frame(4)));
                app.GaitCycleLamp_4.Enable='on';
                app.GaitCycleLamp_4.Color='green';
            else
                if(gait_cycle_count < 4)
                    app.UIAxes3_5.Visible='off';
                    plot(app.UIAxes3_5,0,0);
                    app.GaitCycleLamp_4.Color='white';
                else
                    app.UIAxes3_5.Visible='on';
                    plot(app.UIAxes3_5,s_frame(4):e_frame(4),app.trimmed_coord(s_frame(4):e_frame(4)));
                    app.GaitCycleLamp_4.Color='red';
                end
            end
            
            results = "";
            
           if(app.anatomical_region == "Hindlimb")
                light_hindlimb_error_lamps(app);
           else
            light_error_lamps(app);
           end
            
        end %function plot_cycles
        
    function results = light_error_lamps(app)
    
        s = app.segment_error_checks;
       
        
        if(s(1).RGT_RLE)
            app.RGT_RLE_1.Color = 'green';
        else
            app.RGT_RLE_1.Color = 'red';
        end
        
        if(s(1).RLO_RLS)
            app.RLO_RLS_1.Color = 'green';
        else
            app.RLO_RLS_1.Color = 'red';
        end
        
        if(s(1).RDS_Centroid)
            app.RDS_CENTROID_1.Color = 'green';
        else
            app.RDS_CENTROID_1.Color = 'red';
        end
        
        if(s(1).T1_Centroid)
            app.T1_CENTROID_1.Color = 'green';
        else
            app.T1_CENTROID_1.Color = 'red';
        end
        
        if(s(1).ACB_R5M)
            app.ACB_R5M_1.Color = 'green';
        else
            app.ACB_R5M_1.Color = 'red';
        end
       
        
        if(app.gait_cycle_count >= 2 && s(2).RGT_RLE)
            app.RGT_RLE_2.Color = 'green';
        else
            if(app.gait_cycle_count < 2)
                app.RGT_RLE_2.Color = 'white';
                
            else
              app.RGT_RLE_2.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 2 && s(2).RLO_RLS)
            app.RLO_RLS_2.Color = 'green';
        else
            if(app.gait_cycle_count < 2)
                app.RLO_RLS_2.Color = 'white';
            else
              app.RLO_RLS_2.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 2 && s(2).RDS_Centroid)
            app.RDS_CENTROID_2.Color = 'green';
        else
            if(app.gait_cycle_count < 2)
                app.RDS_CENTROID_2.Color = 'white';
            else
                app.RDS_CENTROID_2.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 2 && s(2).T1_Centroid)
            app.T1_CENTROID_2.Color = 'green';
        else
            if(app.gait_cycle_count < 2)
                app.T1_CENTROID_2.Color = 'white';
            else
                app.T1_CENTROID_2.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 2 && s(2).ACB_R5M)
            app.ACB_R5M_2.Color = 'green';
        else
            if(app.gait_cycle_count < 2)
                app.ACB_R5M_2.Color = 'white';
            else
                app.ACB_R5M_2.Color = 'red';
            end
        end
        
        
        if(app.gait_cycle_count >= 3 && s(3).RGT_RLE)
            app.RGT_RLE_3.Color = 'green';
        else
            if(app.gait_cycle_count < 3)
                app.RGT_RLE_3.Color = 'white';
            else
                app.RGT_RLE_3.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 3 && s(3).RLO_RLS)
            app.RLO_RLS_3.Color = 'green';
        else
            if(app.gait_cycle_count < 3)
                app.RLO_RLS_3.Color = 'white';
            else
                app.RLO_RLS_3.Color = 'red';
            end      
        end
        
        if(app.gait_cycle_count >= 3 && s(3).RDS_Centroid)
            app.RDS_CENTROID_3.Color = 'green';
        else
            if(app.gait_cycle_count < 3)
                app.RDS_CENTROID_3.Color = 'white';
            else
                app.RDS_CENTROID_3.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 3 && s(3).T1_Centroid)
            app.T1_CENTROID_3.Color = 'green';
        else        
                if(app.gait_cycle_count < 3)
                   app.T1_CENTROID_3.Color = 'white';
                else
                   app.T1_CENTROID_3.Color = 'red';
                end     
        end
        
        if(app.gait_cycle_count >= 3 && s(3).ACB_R5M)
            app.ACB_R5M_3.Color = 'green';
        else
            if(app.gait_cycle_count < 3)
                app.ACB_R5M_3.Color = 'white';
            else
                app.ACB_R5M_3.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 4 && s(4).RGT_RLE)
            app.RGT_RLE_4.Color = 'green';
        else
            if(app.gait_cycle_count < 4)
                app.RGT_RLE_4.Color = 'white';
            else
                app.RGT_RLE_4.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 4 && s(4).RLO_RLS)
            app.RLO_RLS_4.Color = 'green';
        else
            if(app.gait_cycle_count < 4)
                app.RLO_RLS_4.Color = 'white';
            else
                app.RLO_RLS_4.Color = 'red';
            end      
        end
        
        if(app.gait_cycle_count >= 4 && s(4).RDS_Centroid)
            app.RDS_CENTROID_4.Color = 'green';
        else
            if(app.gait_cycle_count < 4)
                app.RDS_CENTROID_4.Color = 'white';
            else
                app.RDS_CENTROID_4.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 4 && s(4).T1_Centroid)
            app.T1_CENTROID_4.Color = 'green';
        else        
                if(app.gait_cycle_count < 4)
                   app.T1_CENTROID_4.Color = 'white';
                else
                   app.T1_CENTROID_4.Color = 'red';
                end     
        end
        
        if(app.gait_cycle_count >= 4 && s(4).ACB_R5M)
            app.ACB_R5M_4.Color = 'green';
        else
            if(app.gait_cycle_count < 4)
                app.ACB_R5M_4.Color = 'white';
            else
                app.ACB_R5M_4.Color = 'red';
            end
        end
        
        
        results = "";
         
    end %function light_error_lamps
    
   function results = light_hindlimb_error_lamps(app)
    
        s = app.segment_error_checks;
       
        
        if(s(1).GT_LEP)
            app.RGT_RLE_1.Color = 'green';
        else
            app.RGT_RLE_1.Color = 'red';
        end
        
        if(s(1).FH_LMA)
            app.RLO_RLS_1.Color = 'green';
        else
            app.RLO_RLS_1.Color = 'red';
        end
        
        if(s(1).CAL_MP5)
            app.ACB_R5M_1.Color = 'green';
        else
            app.ACB_R5M_1.Color = 'red';
        end
        
        if(s(1).IWG_ISC)
            app.RDS_CENTROID_1.Color = 'green';
        else
            app.RDS_CENTROID_1.Color = 'red';
        end
        
        if(s(1).CRS_ISC)
            app.T1_CENTROID_1.Color = 'green';
        else
            app.T1_CENTROID_1.Color = 'red';
        end
                
        if(app.gait_cycle_count >= 2 && s(2).GT_LEP)
            app.RGT_RLE_2.Color = 'green';
        else
            if(app.gait_cycle_count < 2)
                app.RGT_RLE_2.Color = 'white';
                
            else
              app.RGT_RLE_2.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 2 && s(2).FH_LMA)
            app.RLO_RLS_2.Color = 'green';
        else
            if(app.gait_cycle_count < 2)
                app.RLO_RLS_2.Color = 'white';
            else
              app.RLO_RLS_2.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 2 && s(2).CAL_MP5)
            app.ACB_R5M_2.Color = 'green';
        else
            if(app.gait_cycle_count < 2)
                app.ACB_R5M_2.Color = 'white';
            else
                app.ACB_R5M_2.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 2 && s(2).IWG_ISC)
            app.RDS_CENTROID_2.Color = 'green';
        else
            if(app.gait_cycle_count < 2)
                app.RDS_CENTROID_2.Color = 'white';
            else
                app.RDS_CENTROID_2.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 2 && s(2).CRS_ISC)
            app.T1_CENTROID_2.Color = 'green';
        else
            if(app.gait_cycle_count < 2)
                app.T1_CENTROID_2.Color = 'white';
            else
                app.T1_CENTROID_2.Color = 'red';
            end
        end
        
        
       if(app.gait_cycle_count >= 3 && s(3).GT_LEP)
            app.RGT_RLE_3.Color = 'green';
        else
            if(app.gait_cycle_count < 3)
                app.RGT_RLE_3.Color = 'white';
                
            else
              app.RGT_RLE_3.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 3 && s(3).FH_LMA)
            app.RLO_RLS_3.Color = 'green';
        else
            if(app.gait_cycle_count < 3)
                app.RLO_RLS_3.Color = 'white';
            else
              app.RLO_RLS_3.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 3 && s(3).CAL_MP5)
            app.ACB_R5M_3.Color = 'green';
        else
            if(app.gait_cycle_count < 3)
                app.ACB_R5M_3.Color = 'white';
            else
                app.ACB_R5M_3.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 3 && s(3).IWG_ISC)
            app.RDS_CENTROID_3.Color = 'green';
        else
            if(app.gait_cycle_count < 3)
                app.RDS_CENTROID_3.Color = 'white';
            else
                app.RDS_CENTROID_3.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 3 && s(3).CRS_ISC)
            app.T1_CENTROID_3.Color = 'green';
        else
            if(app.gait_cycle_count < 3)
                app.T1_CENTROID_3.Color = 'white';
            else
                app.T1_CENTROID_3.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 4 && s(4).GT_LEP)
            app.RGT_RLE_4.Color = 'green';
        else
            if(app.gait_cycle_count < 4)
                app.RGT_RLE_4.Color = 'white';
                
            else
              app.RGT_RLE_4.Color = 'red';
            end
        end

        if(app.gait_cycle_count >= 4 && s(4).FH_LMA)
            app.RLO_RLS_4.Color = 'green';
        else
            if(app.gait_cycle_count < 4)
                app.RLO_RLS_4.Color = 'white';
            else
              app.RLO_RLS_4.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 4 && s(4).CAL_MP5)
            app.ACB_R5M_4.Color = 'green';
        else
            if(app.gait_cycle_count < 4)
                app.ACB_R5M_4.Color = 'white';
            else
                app.ACB_R5M_4.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 4 && s(4).IWG_ISC)
            app.RDS_CENTROID_4.Color = 'green';
        else
            if(app.gait_cycle_count < 4)
                app.RDS_CENTROID_4.Color = 'white';
            else
                app.RDS_CENTROID_4.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 4 && s(4).CRS_ISC)
            app.T1_CENTROID_4.Color = 'green';
        else
            if(app.gait_cycle_count < 4)
                app.T1_CENTROID_4.Color = 'white';
            else
                app.T1_CENTROID_4.Color = 'red';
            end
        end


        results = "";
         
   end %function light_hindlimb_error_lamps
    
        
        function results = change_labels(app,landmark)
            if(landmark == "Forelimb")
                app.anatomical_region = "Forelimb";
                app.MECheckBox.Text = "ME";             
                app.ACBCheckBox.Text = "ACB";
                app.T1CheckBox.Text = "T1";
                app.MSCheckBox.Text = "MS";
                app.MCP2CheckBox.Text = "MCP2";
           
                app.DSCheckBox.Visible = 1;
                app.RGT_RLELabel.Text = "RGT_RLE";
                app.RGT_RLELabel_2.Text = "RGT_RLE";
                app.RGT_RLELabel_3.Text = "RGT_RLE";
                app.RGT_RLELabel_4.Text = "RGT_RLE";
                app.RLO_RLSLabel.Text = "RLO_RLS";
                app.RLO_RLSLabel_2.Text = "RLO_RLS";
                app.RLO_RLSLabel_3.Text = "RLO_RLS";
                app.RLO_RLSLabel_4.Text = "RLO_RLS";
                app.RDS_CentroidLabel.Text = "RDS_CENTROID";
                app.RDS_CentroidLabel_2.Text = "RDS_CENTROID";
                app.RDS_CentroidLabel_3.Text = "RDS_CENTROID";
                app.RDS_CentroidLabel_4.Text = "RDS_CENTROID";
                app.T1_CentroidLabel.Text = "T1_CENTROID";
                app.T1_CentroidLabel_2.Text = "T1_CENTROID";
                app.T1_CentroidLabel_3.Text = "T1_CENTROID";
                app.T1_CentroidLabel_4.Text = "T1_CENTROID";
                app.ACB_R5MLabel.Text = "ACB_R5M";
                app.ACB_R5MLabel_2.Text = "ACB_R5M";
                app.ACB_R5MLabel_3.Text = "ACB_R5M";
                app.ACB_R5MLabel_4.Text = "ACB_R5M";               
            else
                app.anatomical_region = "Hindlimb";
                app.MECheckBox.Text = "MEP";             
                app.ACBCheckBox.Text = "MMA";
                app.T1CheckBox.Text = "LEP";
                app.MSCheckBox.Text = "FH";
                app.MCP2CheckBox.Text = "MP2";
                app.DSCheckBox.Text = "CAL";
                
                
                app.RGT_RLELabel.Text = "GT_LEP";
                app.RGT_RLELabel_2.Text = "GT_LEP";
                app.RGT_RLELabel_3.Text = "GT_LEP";
                app.RGT_RLELabel_4.Text = "GT_LEP";
                app.RLO_RLSLabel.Text = "FH_LMA";
                app.RLO_RLSLabel_2.Text = "FH_LMA";
                app.RLO_RLSLabel_3.Text = "FH_LMA";
                app.RLO_RLSLabel_4.Text = "FH_LMA";
                app.RDS_CentroidLabel.Text = "IWG_ISC";
                app.RDS_CentroidLabel_2.Text = "IWG_ISC";
                app.RDS_CentroidLabel_3.Text = "IWG_ISC";
                app.RDS_CentroidLabel_4.Text = "IWG_ISC";
                app.T1_CentroidLabel.Text = "CRS_ISC";
                app.T1_CentroidLabel_2.Text = "CRS_ISC";
                app.T1_CentroidLabel_3.Text = "CRS_ISC";
                app.T1_CentroidLabel_4.Text = "CRS_ISC";
                app.ACB_R5MLabel.Text = "CAL_MP5";
                app.ACB_R5MLabel_2.Text = "CAL_MP5";
                app.ACB_R5MLabel_3.Text = "CAL_MP5";
                app.ACB_R5MLabel_4.Text = "CAL_MP5";
            end
        end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
% try
               
    override_arr = [app.RGT_RLE_o1.Value app.RGT_RLE_o2.Value app.RGT_RLE_o3.Value app.RGT_RLE_o4.Value 
                  app.RLO_RLS_o1.Value app.RLO_RLS_o2.Value app.RLO_RLS_o3.Value app.RLO_RLS_o4.Value
                  app.RDS_Centroid_o1.Value app.RDS_Centroid_o2.Value app.RDS_Centroid_o3.Value app.RDS_Centroid_o4.Value
                  app.T1_Centroid_o1.Value app.T1_Centroid_o2.Value app.T1_Centroid_o3.Value app.T1_Centroid_o4.Value 
                  app.ACB_R5M_o1.Value app.ACB_R5M_o2.Value app.ACB_R5M_o3.Value app.ACB_R5M_o4.Value];
              
    reconstruct_arr = [app.T1CheckBox.Value app.DSCheckBox.Value app.ACBCheckBox.Value
                        app.MECheckBox.Value app.MSCheckBox.Value app.MCP2CheckBox.Value];
              
    app.gc_override_arr = [app.GC1_OVR.Value app.GC2_OVR.Value app.GC3_OVR.Value app.GC4_OVR.Value];
                                            
    if(app.anatomical_region == "Hindlimb")
        app.error_length = str2double(app.error_length);
        [app.landmark_coord, app.trimmed_coord, app.cycle_time,app.gait_cycle_locations] = hindlimb(app.dynamic_trial,app.name,app.static_trial,app.error_length,override_arr,app.show_graphs,app.gc_override_arr,reconstruct_arr,app.dir_adjust,app.show_reconstr_graphs);
    else
        app.anatomical_region
        app.error_length = str2double(app.error_length);
            [app.landmark_coord, app.trimmed_coord, app.cycle_time,app.gait_cycle_locations] = main(app.dynamic_trial,app.name,app.static_trial,app.error_length,override_arr,app.show_graphs,app.gc_override_arr,reconstruct_arr,app.dir_adjust,app.show_reconstr_graphs);
    end
               
    app.good_cycle_count = 0;
 
    plot_cycles(app);


    app.PermittableErrorLengthmmEditField.Value = "";
        end

        % Value changed function: PermittableErrorLengthmmEditField
        function PermittableErrorLengthmmEditFieldValueChanged(app, event)
            app.error_length = app.PermittableErrorLengthmmEditField.Value;         
        end

        % Callback function
        function PermittableSkinErrorEditFieldValueChanged(app, event)
            app.error_percent = app.PermittableSkinErrorEditField.Value;
        end

        % Value changed function: ShowstaticvsdyngraphsCheckBox
        function ShowstaticvsdyngraphsCheckBoxValueChanged(app, event)
            value = app.ShowstaticvsdyngraphsCheckBox.Value;
            app.show_graphs = value;
            
        end

        % Value changed function: LandmarkDropDown
        function LandmarkDropDownValueChanged(app, event)
            value = app.LandmarkDropDown.Value;
            change_labels(app,value);
        end

        % Button pushed function: ChooseDynamicTrialButton
        function ChooseDynamicTrialButtonPushed(app, event)
            [path dynamic_trial] = uigetfile('*.xls');
            app.name = path;
            app.LoadedDynamicTrialEditField.Value = path;
            app.dynamic_trial = strcat(dynamic_trial,path);

        end

        % Button pushed function: ChooseStaticTrialButton
        function ChooseStaticTrialButtonPushed(app, event)
            [path static_trial] = uigetfile('.xls');
            app.LoadedStaticTrialEditField.Value = path;
            app.static_trial = strcat(static_trial,path);
        end

        % Callback function
        function LeftsideofdogCheckBoxValueChanged(app, event)
            value = app.LeftsideofdogCheckBox.Value;
            app.opp_direction = value;
            
        end

        % Value changed function: 
        % AutomaticdirectionadjustmentCheckBox
        function AutomaticdirectionadjustmentCheckBoxValueChanged(app, event)
            value = app.AutomaticdirectionadjustmentCheckBox.Value;
            app.dir_adjust = value;
        end

        % Value changed function: 
        % ShowreconstructedmarkergraphsCheckBox
        function ShowreconstructedmarkergraphsCheckBoxValueChanged(app, event)
            value = app.ShowreconstructedmarkergraphsCheckBox.Value;
            app.show_reconstr_graphs = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GaitCyclesPanel = uipanel(app.UIFigure);
            app.GaitCyclesPanel.Title = 'Gait Cycles';
            app.UIAxes3 = uiaxes(app.GaitCyclesPanel);
            title(app.UIAxes3, 'Z-coords of landmark')
            xlabel(app.UIAxes3, 'frame (#)')
            ylabel(app.UIAxes3, 'position (mm)')
            app.UIAxes3_2 = uiaxes(app.GaitCyclesPanel);
            title(app.UIAxes3_2, 'Gait Cycle 1')
            xlabel(app.UIAxes3_2, 'frame (#)')
            ylabel(app.UIAxes3_2, 'position (mm)')
            app.UIAxes3_3 = uiaxes(app.GaitCyclesPanel);
            title(app.UIAxes3_3, 'Gait Cycle 2')
            xlabel(app.UIAxes3_3, 'frame (#)')
            ylabel(app.UIAxes3_3, '')
            app.UIAxes3_4 = uiaxes(app.GaitCyclesPanel);
            title(app.UIAxes3_4, 'Gait Cycle 3')
            xlabel(app.UIAxes3_4, 'frame (#)')
            ylabel(app.UIAxes3_4, '')
            app.RGT_RLELabel = uilabel(app.GaitCyclesPanel);
            app.RGT_RLELabel.Text = 'RGT_RLE:';
            app.RLO_RLSLabel = uilabel(app.GaitCyclesPanel);
            app.RLO_RLSLabel.Text = 'RLO_RLS:';
            app.RDS_CentroidLabel = uilabel(app.GaitCyclesPanel);
            app.RDS_CentroidLabel.Text = 'RDS_Centroid:';
            app.T1_CentroidLabel = uilabel(app.GaitCyclesPanel);
            app.T1_CentroidLabel.Text = 'T1_Centroid:';
            app.RGT_RLE_1 = uilamp(app.GaitCyclesPanel);
            app.RLO_RLS_1 = uilamp(app.GaitCyclesPanel);
            app.RDS_CENTROID_1 = uilamp(app.GaitCyclesPanel);
            app.T1_CENTROID_1 = uilamp(app.GaitCyclesPanel);
            app.RGT_RLELabel_2 = uilabel(app.GaitCyclesPanel);
            app.RGT_RLELabel_2.Text = 'RGT_RLE:';
            app.RLO_RLSLabel_2 = uilabel(app.GaitCyclesPanel);
            app.RLO_RLSLabel_2.Text = 'RLO_RLS:';
            app.RDS_CentroidLabel_2 = uilabel(app.GaitCyclesPanel);
            app.RDS_CentroidLabel_2.Text = 'RDS_Centroid:';
            app.T1_CentroidLabel_2 = uilabel(app.GaitCyclesPanel);
            app.T1_CentroidLabel_2.Text = 'T1_Centroid:';
            app.RGT_RLE_2 = uilamp(app.GaitCyclesPanel);
            app.RLO_RLS_2 = uilamp(app.GaitCyclesPanel);
            app.RDS_CENTROID_2 = uilamp(app.GaitCyclesPanel);
            app.T1_CENTROID_2 = uilamp(app.GaitCyclesPanel);
            app.RGT_RLELabel_3 = uilabel(app.GaitCyclesPanel);
            app.RGT_RLELabel_3.Text = 'RGT_RLE:';
            app.RLO_RLSLabel_3 = uilabel(app.GaitCyclesPanel);
            app.RLO_RLSLabel_3.Text = 'RLO_RLS:';
            app.RDS_CentroidLabel_3 = uilabel(app.GaitCyclesPanel);
            app.RDS_CentroidLabel_3.Text = 'RDS_Centroid:';
            app.T1_CentroidLabel_3 = uilabel(app.GaitCyclesPanel);
            app.T1_CentroidLabel_3.Text = 'T1_Centroid:';
            app.RGT_RLE_3 = uilamp(app.GaitCyclesPanel);
            app.RLO_RLS_3 = uilamp(app.GaitCyclesPanel);
            app.RDS_CENTROID_3 = uilamp(app.GaitCyclesPanel);
            app.T1_CENTROID_3 = uilamp(app.GaitCyclesPanel);
            app.UIAxes3_5 = uiaxes(app.GaitCyclesPanel);
            title(app.UIAxes3_5, 'Gait Cycle 4')
            xlabel(app.UIAxes3_5, 'frame (#)')
            ylabel(app.UIAxes3_5, '')
            app.RGT_RLELabel_4 = uilabel(app.GaitCyclesPanel);
            app.RGT_RLELabel_4.Text = 'RGT_RLE:';
            app.RLO_RLSLabel_4 = uilabel(app.GaitCyclesPanel);
            app.RLO_RLSLabel_4.Text = 'RLO_RLS:';
            app.RDS_CentroidLabel_4 = uilabel(app.GaitCyclesPanel);
            app.RDS_CentroidLabel_4.Text = 'RDS_Centroid:';
            app.T1_CentroidLabel_4 = uilabel(app.GaitCyclesPanel);
            app.T1_CentroidLabel_4.Text = 'T1_Centroid:';
            app.RGT_RLE_4 = uilamp(app.GaitCyclesPanel);
            app.RLO_RLS_4 = uilamp(app.GaitCyclesPanel);
            app.RDS_CENTROID_4 = uilamp(app.GaitCyclesPanel);
            app.T1_CENTROID_4 = uilamp(app.GaitCyclesPanel);
            app.RGT_RLE_o1 = uicheckbox(app.GaitCyclesPanel);
            app.RGT_RLE_o1.Text = ' Override';
            app.ACB_R5MLabel = uilabel(app.GaitCyclesPanel);
            app.ACB_R5MLabel.Text = 'ACB_R5M';
            app.ACB_R5M_1 = uilamp(app.GaitCyclesPanel);
            app.ACB_R5MLabel_2 = uilabel(app.GaitCyclesPanel);
            app.ACB_R5MLabel_2.Text = 'ACB_R5M';
            app.ACB_R5M_2 = uilamp(app.GaitCyclesPanel);
            app.ACB_R5MLabel_3 = uilabel(app.GaitCyclesPanel);
            app.ACB_R5MLabel_3.Text = 'ACB_R5M';
            app.ACB_R5M_3 = uilamp(app.GaitCyclesPanel);
            app.ACB_R5MLabel_4 = uilabel(app.GaitCyclesPanel);
            app.ACB_R5MLabel_4.Text = 'ACB_R5M';
            app.ACB_R5M_4 = uilamp(app.GaitCyclesPanel);
            app.RLO_RLS_o1 = uicheckbox(app.GaitCyclesPanel);
            app.RLO_RLS_o1.Text = '';
            app.RDS_Centroid_o1 = uicheckbox(app.GaitCyclesPanel);
            app.RDS_Centroid_o1.Text = '';
            app.T1_Centroid_o1 = uicheckbox(app.GaitCyclesPanel);
            app.T1_Centroid_o1.Text = '';
            app.ACB_R5M_o1 = uicheckbox(app.GaitCyclesPanel);
            app.ACB_R5M_o1.Text = '';
            app.RLO_RLS_o2 = uicheckbox(app.GaitCyclesPanel);
            app.RLO_RLS_o2.Text = '';
            app.RDS_Centroid_o2 = uicheckbox(app.GaitCyclesPanel);
            app.RDS_Centroid_o2.Text = '';
            app.T1_Centroid_o2 = uicheckbox(app.GaitCyclesPanel);
            app.T1_Centroid_o2.Text = '';
            app.ACB_R5M_o2 = uicheckbox(app.GaitCyclesPanel);
            app.ACB_R5M_o2.Text = '';
            app.RLO_RLS_o3 = uicheckbox(app.GaitCyclesPanel);
            app.RLO_RLS_o3.Text = '';
            app.RDS_Centroid_o3 = uicheckbox(app.GaitCyclesPanel);
            app.RDS_Centroid_o3.Text = '';
            app.T1_Centroid_o3 = uicheckbox(app.GaitCyclesPanel);
            app.T1_Centroid_o3.Text = '';
            app.ACB_R5M_o3 = uicheckbox(app.GaitCyclesPanel);
            app.ACB_R5M_o3.Text = '';
            app.RLO_RLS_o4 = uicheckbox(app.GaitCyclesPanel);
            app.RLO_RLS_o4.Text = '';
            app.RDS_Centroid_o4 = uicheckbox(app.GaitCyclesPanel);
            app.RDS_Centroid_o4.Text = '';
            app.T1_Centroid_o4 = uicheckbox(app.GaitCyclesPanel);
            app.T1_Centroid_o4.Text = '';
            app.ACB_R5M_o4 = uicheckbox(app.GaitCyclesPanel);
            app.ACB_R5M_o4.Text = '';
            app.RGT_RLE_o2 = uicheckbox(app.GaitCyclesPanel);
            app.RGT_RLE_o2.Text = '';
            app.RGT_RLE_o3 = uicheckbox(app.GaitCyclesPanel);
            app.RGT_RLE_o3.Text = '';
            app.RGT_RLE_o4 = uicheckbox(app.GaitCyclesPanel);
            app.RGT_RLE_o4.Text = '';
            app.GC1_OVR = uicheckbox(app.GaitCyclesPanel);
            app.GC1_OVR.Text = '';
            app.GC2_OVR = uicheckbox(app.GaitCyclesPanel);
            app.GC2_OVR.Text = '';
            app.GC3_OVR = uicheckbox(app.GaitCyclesPanel);
            app.GC3_OVR.Text = '';
            app.GC4_OVR = uicheckbox(app.GaitCyclesPanel);
            app.GC4_OVR.Text = '';
            app.GoodgaitcyclesEditFieldLabel = uilabel(app.GaitCyclesPanel);
            app.GoodgaitcyclesEditFieldLabel.Text = 'Good gait cycles:';
            app.GoodgaitcyclesEditField = uieditfield(app.GaitCyclesPanel, 'numeric');
            app.GoodgaitcyclesEditField.Editable = 'off';
            app.GaitCycleLampLabel = uilabel(app.GaitCyclesPanel);
            app.GaitCycleLampLabel.Text = 'Gait Cycle';
            app.GaitCycleLamp = uilamp(app.GaitCyclesPanel);
            app.GaitCycleLamp_2Label = uilabel(app.GaitCyclesPanel);
            app.GaitCycleLamp_2Label.Text = 'Gait Cycle';
            app.GaitCycleLamp_2 = uilamp(app.GaitCyclesPanel);
            app.GaitCycleLamp_3Label = uilabel(app.GaitCyclesPanel);
            app.GaitCycleLamp_3Label.Text = 'Gait Cycle';
            app.GaitCycleLamp_3 = uilamp(app.GaitCyclesPanel);
            app.GaitCycleLamp_4Label = uilabel(app.GaitCyclesPanel);
            app.GaitCycleLamp_4Label.Text = 'Gait Cycle';
            app.GaitCycleLamp_4 = uilamp(app.GaitCyclesPanel);
            app.MarkerReconstructionsLabel = uilabel(app.GaitCyclesPanel);
            app.MarkerReconstructionsLabel.Text = 'Marker Reconstructions';
            app.T1CheckBox = uicheckbox(app.GaitCyclesPanel);
            app.T1CheckBox.Text = 'T1';
            app.DSCheckBox = uicheckbox(app.GaitCyclesPanel);
            app.DSCheckBox.Text = 'DS';
            app.ACBCheckBox = uicheckbox(app.GaitCyclesPanel);
            app.ACBCheckBox.Text = 'ACB';
            app.MECheckBox = uicheckbox(app.GaitCyclesPanel);
            app.MECheckBox.Text = 'ME';
            app.MECheckBox.Value = true;
            app.MSCheckBox = uicheckbox(app.GaitCyclesPanel);
            app.MSCheckBox.Text = 'MS';
            app.MCP2CheckBox = uicheckbox(app.GaitCyclesPanel);
            app.MCP2CheckBox.Text = 'MCP2';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LandmarkDropDown = uidropdown(app.UIFigure);
            app.LandmarkDropDown.Items = {'Forelimb', 'Hindlimb'};
            app.LandmarkDropDown.ValueChangedFcn = createCallbackFcn(app, @LandmarkDropDownValueChanged, true);
            app.LandmarkDropDown.Value = 'Forelimb';
            app.PermittableErrorLengthmmEditFieldLabel = uilabel(app.UIFigure);
            app.PermittableErrorLengthmmEditFieldLabel.Text = 'Permittable Error Length (mm)';
            app.PermittableErrorLengthmmEditField = uieditfield(app.UIFigure, 'text');
            app.PermittableErrorLengthmmEditField.ValueChangedFcn = createCallbackFcn(app, @PermittableErrorLengthmmEditFieldValueChanged, true);
            app.PermittableErrorLengthmmEditField.Value = '50';
            app.ShowstaticvsdyngraphsCheckBox = uicheckbox(app.UIFigure);
            app.ShowstaticvsdyngraphsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowstaticvsdyngraphsCheckBoxValueChanged, true);
            app.ShowstaticvsdyngraphsCheckBox.Text = 'Show static vs dyn. graphs';
            app.ChooseDynamicTrialButton = uibutton(app.UIFigure, 'push');
            app.ChooseDynamicTrialButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDynamicTrialButtonPushed, true);
            app.ChooseDynamicTrialButton.Text = 'Choose Dynamic Trial';
            app.ChooseStaticTrialButton = uibutton(app.UIFigure, 'push');
            app.ChooseStaticTrialButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseStaticTrialButtonPushed, true);
            app.ChooseStaticTrialButton.Text = 'Choose Static Trial';
            app.LoadedDynamicTrialEditFieldLabel = uilabel(app.UIFigure);
            app.LoadedDynamicTrialEditFieldLabel.Text = 'Loaded Dynamic Trial:';
            app.LoadedDynamicTrialEditField = uieditfield(app.UIFigure, 'text');
            app.LoadedStaticTrialEditFieldLabel = uilabel(app.UIFigure);
            app.LoadedStaticTrialEditFieldLabel.Text = 'Loaded Static Trial:';
            app.LoadedStaticTrialEditField = uieditfield(app.UIFigure, 'text');
            app.AutomaticdirectionadjustmentCheckBox = uicheckbox(app.UIFigure);
            app.AutomaticdirectionadjustmentCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutomaticdirectionadjustmentCheckBoxValueChanged, true);
            app.AutomaticdirectionadjustmentCheckBox.Text = 'Automatic direction adjustment';
            app.AutomaticdirectionadjustmentCheckBox.Value = true;
            app.ShowreconstructedmarkergraphsCheckBox = uicheckbox(app.UIFigure);
            app.ShowreconstructedmarkergraphsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowreconstructedmarkergraphsCheckBoxValueChanged, true);
            app.ShowreconstructedmarkergraphsCheckBox.Text = 'Show reconstructed marker graphs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VetProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end