classdef postprocess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        joint                           matlab.ui.control.DropDown
        JointLabel                      matlab.ui.control.Label
        AddAnglematButton               matlab.ui.control.Button
        GC_1                            matlab.ui.control.UIAxes
        GC_2                            matlab.ui.control.UIAxes
        GC_3                            matlab.ui.control.UIAxes
        GC_4                            matlab.ui.control.UIAxes
        ExporttoSpreadsheetButton       matlab.ui.control.Button
        PlaneDropDownLabel              matlab.ui.control.Label
        PlaneDropDown                   matlab.ui.control.DropDown
        DynamicmatLoadedEditFieldLabel  matlab.ui.control.Label
        DynamicmatLoadedEditField       matlab.ui.control.EditField
        Tree                            matlab.ui.container.Tree
        Trial1Node                      matlab.ui.container.TreeNode
        GC1Node                         matlab.ui.container.TreeNode
        GC2Node                         matlab.ui.container.TreeNode
        GC3Node                         matlab.ui.container.TreeNode
        Trialn1Node                     matlab.ui.container.TreeNode
        TrialnNode                      matlab.ui.container.TreeNode
        resampled_avg                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        average = 0;
        trial_name;
        joint_number = 1;
        plane_number = 1;
        gc_frames;
        trial_angles;
        resampled_angles;
        gc_count = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExporttoSpreadsheetButton
        function ExporttoSpreadsheetButtonPushed(app, event)
            app.average = app.average + 1;
            disp(app.average)
        end

        % Button pushed function: AddAnglematButton
        function AddAnglematButtonPushed(app, event)
            % When a mat file is chosen, clicking that button should begin
            % to extract the numbers from that mat and run it through
            % resample.m
            
            app.trial_name = uigetfile('*.mat');
            app.DynamicmatLoadedEditField.Value = app.trial_name;
            [trial_resampled_angles,trial_gc_count,app.gc_frames,app.trial_angles] = resample_trial(app.trial_name,app.plane_number,app.joint_number);
            
            app.gc_count = app.gc_count + trial_gc_count;
            app.resampled_angles = [app.resampled_angles; trial_resampled_angles];
            % TODO: create structure for multi-trial functionality
            
            graphs = [app.GC_1,app.GC_2,app.GC_3,app.GC_4];
            
            for i = 1:trial_gc_count
                plot(graphs(i),app.gc_frames(i):app.gc_frames(i+1),app.trial_angles(app.gc_frames(i):app.gc_frames(i+1)));
            end
            
            plot(app.resampled_avg,1:length(app.resampled_angles),app.resampled_angles);


        end

        % Value changed function: joint
        function jointValueChanged(app, event)
            value = app.joint.Value;
            
            switch value
                case "Shoulder"
                    app.joint_number = 1;
                case "Elbow"
                    app.joint_number = 2;
                case "Carpus"
                    app.joint_number = 3;
            end         
        end

        % Value changed function: PlaneDropDown
        function PlaneDropDownValueChanged(app, event)
            value = app.PlaneDropDown.Value;
            
            switch value
                case "F/E"
                    app.plane_number = 1;
                case "Int/Ext"
                    app.plane_number = 2;
                case "Add/Abd"
                    app.plane_number = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.joint = uidropdown(app.UIFigure);
            app.joint.Items = {'Shoulder', 'Elbow', 'Carpus'};
            app.joint.ValueChangedFcn = createCallbackFcn(app, @jointValueChanged, true);
            app.joint.Value = 'Shoulder';
            app.JointLabel = uilabel(app.UIFigure);
            app.JointLabel.Text = 'Joint';
            app.AddAnglematButton = uibutton(app.UIFigure, 'push');
            app.AddAnglematButton.ButtonPushedFcn = createCallbackFcn(app, @AddAnglematButtonPushed, true);
            app.AddAnglematButton.Text = 'Add Angle .mat';
            app.GC_1 = uiaxes(app.UIFigure);
            title(app.GC_1, 'GC 1')
            xlabel(app.GC_1, 'X')
            ylabel(app.GC_1, 'Y')
            app.GC_2 = uiaxes(app.UIFigure);
            title(app.GC_2, 'GC 2')
            xlabel(app.GC_2, 'X')
            ylabel(app.GC_2, 'Y')
            app.GC_3 = uiaxes(app.UIFigure);
            title(app.GC_3, 'GC 3')
            xlabel(app.GC_3, 'X')
            ylabel(app.GC_3, 'Y')
            app.GC_4 = uiaxes(app.UIFigure);
            title(app.GC_4, 'GC 4')
            xlabel(app.GC_4, 'X')
            ylabel(app.GC_4, 'Y')
            app.ExporttoSpreadsheetButton = uibutton(app.UIFigure, 'push');
            app.ExporttoSpreadsheetButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoSpreadsheetButtonPushed, true);
            app.ExporttoSpreadsheetButton.Text = 'Export to Spreadsheet';
            app.PlaneDropDownLabel = uilabel(app.UIFigure);
            app.PlaneDropDownLabel.Text = 'Plane';
            app.PlaneDropDown = uidropdown(app.UIFigure);
            app.PlaneDropDown.Items = {'F/E', 'Int/Ext', 'Add/Abd'};
            app.PlaneDropDown.ValueChangedFcn = createCallbackFcn(app, @PlaneDropDownValueChanged, true);
            app.PlaneDropDown.Value = 'F/E';
            app.DynamicmatLoadedEditFieldLabel = uilabel(app.UIFigure);
            app.DynamicmatLoadedEditFieldLabel.Text = 'Dynamic .mat Loaded:';
            app.DynamicmatLoadedEditField = uieditfield(app.UIFigure, 'text');
            app.Tree = uitree(app.UIFigure);
            app.Tree.Multiselect = 'on';
            app.Trial1Node = uitreenode(app.Tree);
            app.Trial1Node.Text = 'Trial 1';
            app.GC1Node = uitreenode(app.Trial1Node);
            app.GC1Node.Text = 'GC 1';
            app.GC2Node = uitreenode(app.Trial1Node);
            app.GC2Node.Text = 'GC 2';
            app.GC3Node = uitreenode(app.Trial1Node);
            app.GC3Node.Text = 'GC 3';
            app.Trialn1Node = uitreenode(app.Tree);
            app.Trialn1Node.Text = 'Trial n-1';
            app.TrialnNode = uitreenode(app.Tree);
            app.TrialnNode.Text = 'Trial n';
            app.resampled_avg = uiaxes(app.UIFigure);
            title(app.resampled_avg, 'avg')
            xlabel(app.resampled_avg, 'X')
            ylabel(app.resampled_avg, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = postprocess

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end